classdef ProGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeSliderLabel                matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        RecordButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        NoiseCancellationLabel         matlab.ui.control.Label
        PlayNoisecancelledSoundButton  matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        AddNoiseButtonGroup            matlab.ui.container.ButtonGroup
        GaussianButton                 matlab.ui.control.ToggleButton
        Button2_2                      matlab.ui.control.ToggleButton
        Button3_2                      matlab.ui.control.ToggleButton
        RemoveNoiseButtonGroup         matlab.ui.container.ButtonGroup
        LMSButton                      matlab.ui.control.ToggleButton
        NLMSButton                     matlab.ui.control.ToggleButton
        RLSButton                      matlab.ui.control.ToggleButton
        PlayNoiseAddedSoundButton      matlab.ui.control.Button
        PlayOriginalSoundButton        matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function [Sounddata] = soundRec(app,time)
        %UNTITLED Summary of this function goes here
        %   Detailed explanation goes here
        Fs = 8000;
        %[d,Fs] = audioread('music.wav'); % Loading of input signal
        recording = audiorecorder;
        disp('Recording....');
        recordblocking(recording,time); %Recording sound for 10 seconds 
        Sounddata =  getaudiodata(recording);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.Lamp.Color=[0,1,0];
            dd=app.soundRec(app.TimeSlider.Value);
            app.Lamp.Color=[0,0,0];
               sound(dd)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time ';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0 30];
            app.TimeSlider.Value = 5;
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = {'Record'; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NoiseCancellationLabel = uilabel(app.UIFigure);
            app.NoiseCancellationLabel.Text = 'Noise Cancellation';
            app.PlayNoisecancelledSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayNoisecancelledSoundButton.Text = 'Play Noise cancelled Sound';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.AddNoiseButtonGroup = uibuttongroup(app.UIFigure);
            app.AddNoiseButtonGroup.Title = 'Add Noise';
            app.GaussianButton = uitogglebutton(app.AddNoiseButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.GaussianButton.Value = true;
            app.Button2_2 = uitogglebutton(app.AddNoiseButtonGroup);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.AddNoiseButtonGroup);
            app.Button3_2.Text = 'Button3';
            app.RemoveNoiseButtonGroup = uibuttongroup(app.UIFigure);
            app.RemoveNoiseButtonGroup.Title = 'Remove Noise';
            app.LMSButton = uitogglebutton(app.RemoveNoiseButtonGroup);
            app.LMSButton.Text = 'LMS';
            app.LMSButton.Value = true;
            app.NLMSButton = uitogglebutton(app.RemoveNoiseButtonGroup);
            app.NLMSButton.Text = 'NLMS';
            app.RLSButton = uitogglebutton(app.RemoveNoiseButtonGroup);
            app.RLSButton.Text = 'RLS';
            app.PlayNoiseAddedSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayNoiseAddedSoundButton.Text = 'Play Noise Added Sound';
            app.PlayOriginalSoundButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalSoundButton.Text = 'Play Original Sound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end